classdef simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        InitializeButton                matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        PauseButton                     matlab.ui.control.Button
        StatusEditFieldLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        HyperparametersPanel            matlab.ui.container.Panel
        MapsizeEditFieldLabel           matlab.ui.control.Label
        MapsizeEditField                matlab.ui.control.NumericEditField
        NumberofobstacleEditFieldLabel  matlab.ui.control.Label
        NumberofobstacleEditField       matlab.ui.control.NumericEditField
        StamovsplitratioEditFieldLabel  matlab.ui.control.Label
        StamovsplitratioEditField       matlab.ui.control.NumericEditField
        LearningrateEditFieldLabel      matlab.ui.control.Label
        LearningrateEditField           matlab.ui.control.NumericEditField
        DefaultButton                   matlab.ui.control.Button
        R1EditFieldLabel                matlab.ui.control.Label
        R1EditField                     matlab.ui.control.NumericEditField
        R4EditFieldLabel                matlab.ui.control.Label
        R4EditField                     matlab.ui.control.NumericEditField
        R2EditFieldLabel                matlab.ui.control.Label
        R2EditField                     matlab.ui.control.NumericEditField
        R3EditFieldLabel                matlab.ui.control.Label
        R3EditField                     matlab.ui.control.NumericEditField
        R5EditFieldLabel                matlab.ui.control.Label
        R5EditField                     matlab.ui.control.NumericEditField
        R6EditFieldLabel                matlab.ui.control.Label
        R6EditField                     matlab.ui.control.NumericEditField
        RewardLabel                     matlab.ui.control.Label
        NumberofEpisodesEditFieldLabel  matlab.ui.control.Label
        NumberofEpisodesEditField       matlab.ui.control.NumericEditField
    end


    methods (Access = private)
    
        function plot_at_initialization(app)
            global rbt
            global components
            
            cla(app.UIAxes);
            
            % Plotting
            app.UIAxes.XLim = [0 rbt.map_size];
            app.UIAxes.XTick = 0:rbt.step:rbt.map_size;
            app.UIAxes.YLim = [0 rbt.map_size];
            app.UIAxes.YTick = 0:rbt.step:rbt.map_size;
            
            hold(app.UIAxes, 'on');
            x1 = rbt.robot_pos(1)-rbt.size1/2;
            x2 = rbt.robot_pos(1)+rbt.size1/2;
            y1 = rbt.robot_pos(2)-rbt.size2/2;
            y2 = rbt.robot_pos(2)+rbt.size2/2;
            current = polyshape([x1 x2 x2 x1], [y1 y1 y2 y2]); 
            current = rotate(current, -45, [rbt.robot_pos(1) rbt.robot_pos(2)]);
            current = plot(app.UIAxes, current);               
            theta = linspace(0, 2*pi, 36);
            x = rbt.observe_range*cos(theta) + rbt.robot_pos(1);
            y = rbt.observe_range*sin(theta) + rbt.robot_pos(2);
            detect_circle = plot(app.UIAxes, x, y, 'Color', [0.3 0.1 0.7]);
            detect_lines = hggroup(app.UIAxes);
            
            for i=1:length(rbt.detect_angles)-1
                x2 = rbt.robot_pos(1) + (rbt.observe_range*cos(rbt.orientation+rbt.detect_angles(i)));
                y2 = rbt.robot_pos(2) + (rbt.observe_range*sin(rbt.orientation+rbt.detect_angles(i)));
                plot(app.UIAxes, [rbt.robot_pos(1) x2], [rbt.robot_pos(2) y2], ...
                    'LineStyle', '--', 'Color', [0.1 0.1 0.1], 'Parent', detect_lines);
            end
            
            goal = text(app.UIAxes, rbt.goal(1), rbt.goal(2), '\diamondsuit', 'FontSize', 30,...
            'HorizontalAlignment', 'center', 'Color', 'r', 'FontWeight', 'bold');
            
            sta = plot(app.UIAxes, rbt.sta_obs(:,1), rbt.sta_obs(:,2), 'bo', 'MarkerSize', 10);
            mov = plot(app.UIAxes, rbt.orig_mov_obs(:,1), rbt.orig_mov_obs(:,2), 'bo', 'MarkerSize', 10, 'Color', 'r');
            
            robot = struct('current', current, 'detect_circle', detect_circle, 'detect_lines', detect_lines);
            obstacles = struct('sta', sta, 'mov', mov);
            components = struct('robot', robot, 'goal', goal, 'obstacles', obstacles);
        end
        
        function plot_at_simulation(app)
            global rbt
            global components
        
            % Delete old objects
            delete(components.obstacles.sta);
            delete(components.obstacles.mov);
            delete(components.goal);
            delete(components.robot.current);
            delete(components.robot.detect_circle);
            delete(components.robot.detect_lines);
            
            % Plotting
            app.UIAxes.XLim = [0 rbt.map_size];
            app.UIAxes.XTick = 0:rbt.step:rbt.map_size;
            app.UIAxes.YLim = [0 rbt.map_size];
            app.UIAxes.YTick = 0:rbt.step:rbt.map_size;
            
            hold(app.UIAxes, 'on');
            x1 = rbt.robot_pos(1)-rbt.size1/2;
            x2 = rbt.robot_pos(1)+rbt.size1/2;
            y1 = rbt.robot_pos(2)-rbt.size2/2;
            y2 = rbt.robot_pos(2)+rbt.size2/2;
            current = polyshape([x1 x2 x2 x1], [y1 y1 y2 y2]); 
            current = rotate(current, -rbt.orientation*180/pi, [rbt.robot_pos(1) rbt.robot_pos(2)]);
            current = plot(app.UIAxes, current);               
            theta = linspace(0, 2*pi, 36);
            x = rbt.observe_range*cos(theta) + rbt.robot_pos(1);
            y = rbt.observe_range*sin(theta) + rbt.robot_pos(2);
            detect_circle = plot(app.UIAxes, x, y, 'Color', [0.3 0.1 0.7]);
            detect_lines = hggroup(app.UIAxes);
            
            for i=1:length(rbt.detect_angles)-1
                x2 = rbt.robot_pos(1) + (rbt.observe_range*cos(rbt.orientation+rbt.detect_angles(i)));
                y2 = rbt.robot_pos(2) + (rbt.observe_range*sin(rbt.orientation+rbt.detect_angles(i)));
                if rbt.state(length(rbt.state)+1-i) > 0
                    plot(app.UIAxes, [rbt.robot_pos(1) x2], [rbt.robot_pos(2) y2], ...
                        'LineStyle', '--', 'Color', [0.8 0.1 0.1], 'Parent', detect_lines);
                else
                    plot(app.UIAxes, [rbt.robot_pos(1) x2], [rbt.robot_pos(2) y2], ...
                        'LineStyle', '--', 'Color', [0.1 0.1 0.1], 'Parent', detect_lines);
                end
            end

            goal = text(app.UIAxes, rbt.goal(1), rbt.goal(2), '\diamondsuit', 'FontSize', 30,...
            'HorizontalAlignment', 'center', 'Color', 'r', 'FontWeight', 'bold');
            
            sta = plot(app.UIAxes, rbt.sta_obs(:,1), rbt.sta_obs(:,2), 'bo', 'MarkerSize', 10);
            mov = plot(app.UIAxes, rbt.orig_mov_obs(:,1), rbt.orig_mov_obs(:,2), 'bo', 'MarkerSize', 10, 'Color', 'r');
            
            robot = struct('current', current, 'detect_circle', detect_circle, 'detect_lines', detect_lines);
            obstacles = struct('sta', sta, 'mov', mov);
            components = struct('robot', robot, 'goal', goal, 'obstacles', obstacles);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MapsizeEditField.Value = 10;
            app.NumberofobstacleEditField.Value = 5;
            app.StamovsplitratioEditField.Value = 0.6;
            app.LearningrateEditField.Value = 0.01;
            app.NumberofEpisodesEditField.Value = 30;
            
            app.R1EditField.Value = 0.3;
            app.R2EditField.Value = -0.2;
            app.R3EditField.Value = -0.4;
            app.R4EditField.Value = 0.4;
            app.R5EditField.Value = 1;
            app.R6EditField.Value = -0.6;
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            global rbt
            
            app.StatusEditField.FontColor = 'k';
            app.StatusEditField.Value = 'Initialized!';
            
            map_size = app.MapsizeEditField.Value;
            num_obs = app.NumberofobstacleEditField.Value;
            sta_mov_split = app.StamovsplitratioEditField.Value;
            learning_rate = app.LearningrateEditField.Value;
            epsilon = 0.12;
            min_dist = 0.2;
            
            rbt = Robot(map_size, num_obs, sta_mov_split, epsilon, learning_rate, min_dist);
            
            r1 = app.R1EditField.Value;
            r2 = app.R2EditField.Value;
            r3 = app.R3EditField.Value;
            r4 = app.R4EditField.Value;
            r5 = app.R5EditField.Value;
            r6 = app.R6EditField.Value;
            
            rbt = rbt.set_reward_params(r1, r2, r3, r4, r5, r6);
            plot_at_initialization(app);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.MapsizeEditField.Value = 10;
            app.NumberofobstacleEditField.Value = 5;
            app.StamovsplitratioEditField.Value = 0.6;
            app.LearningrateEditField.Value = 0.01;
            app.NumberofEpisodesEditField.Value = 30;
            
            app.R1EditField.Value = 0.3;
            app.R2EditField.Value = -0.2;
            app.R3EditField.Value = -0.4;
            app.R4EditField.Value = 0.4;
            app.R5EditField.Value = 1;
            app.R6EditField.Value = -0.6;
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            global rbt
            
            if  app.StatusEditField.Value == 'Initialized!'
                episodes = app.NumberofEpisodesEditField.Value;
                for i = 1:episodes
                    rbt = rbt.observe_state();
                    rbt = rbt.get_state_property();
                    plot_at_simulation(app);
                    if rbt.state_property == 'FS'
                        app.StatusEditField.Value = 'Training failed!';
                        app.StatusEditField.FontColor = 'r';
                      break;
                    elseif rbt.state_property == 'SS'
                        rbt.orientation = atan((rbt.goal(2) - rbt.robot_pos(2))/(rbt.goal(1) - rbt.robot_pos(1)));
                         
                    end
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Q-value'; 'Optimal Q-value'; 'State property'; 'Reward'; 'Action'};
            app.UITable.RowName = {};
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.HyperparametersPanel = uipanel(app.UIFigure);
            app.HyperparametersPanel.Title = 'Hyper parameters';
            app.MapsizeEditFieldLabel = uilabel(app.HyperparametersPanel);
            app.MapsizeEditFieldLabel.Text = 'Map size';
            app.MapsizeEditField = uieditfield(app.HyperparametersPanel, 'numeric');
            app.NumberofobstacleEditFieldLabel = uilabel(app.HyperparametersPanel);
            app.NumberofobstacleEditFieldLabel.Text = 'Number of obstacle';
            app.NumberofobstacleEditField = uieditfield(app.HyperparametersPanel, 'numeric');
            app.StamovsplitratioEditFieldLabel = uilabel(app.HyperparametersPanel);
            app.StamovsplitratioEditFieldLabel.Text = 'Sta-mov split ratio';
            app.StamovsplitratioEditField = uieditfield(app.HyperparametersPanel, 'numeric');
            app.LearningrateEditFieldLabel = uilabel(app.HyperparametersPanel);
            app.LearningrateEditFieldLabel.Text = 'Learning rate';
            app.LearningrateEditField = uieditfield(app.HyperparametersPanel, 'numeric');
            app.LearningrateEditField.Limits = [0 Inf];
            app.DefaultButton = uibutton(app.HyperparametersPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.R1EditFieldLabel = uilabel(app.HyperparametersPanel);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.HyperparametersPanel, 'numeric');
            app.R4EditFieldLabel = uilabel(app.HyperparametersPanel);
            app.R4EditFieldLabel.Text = 'R4';
            app.R4EditField = uieditfield(app.HyperparametersPanel, 'numeric');
            app.R2EditFieldLabel = uilabel(app.HyperparametersPanel);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.HyperparametersPanel, 'numeric');
            app.R3EditFieldLabel = uilabel(app.HyperparametersPanel);
            app.R3EditFieldLabel.Text = 'R3';
            app.R3EditField = uieditfield(app.HyperparametersPanel, 'numeric');
            app.R5EditFieldLabel = uilabel(app.HyperparametersPanel);
            app.R5EditFieldLabel.Text = 'R5';
            app.R5EditField = uieditfield(app.HyperparametersPanel, 'numeric');
            app.R6EditFieldLabel = uilabel(app.HyperparametersPanel);
            app.R6EditFieldLabel.Text = 'R6';
            app.R6EditField = uieditfield(app.HyperparametersPanel, 'numeric');
            app.RewardLabel = uilabel(app.HyperparametersPanel);
            app.RewardLabel.Text = 'Reward';
            app.NumberofEpisodesEditFieldLabel = uilabel(app.HyperparametersPanel);
            app.NumberofEpisodesEditFieldLabel.Text = 'Number of Episodes';
            app.NumberofEpisodesEditField = uieditfield(app.HyperparametersPanel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = simulation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end